classdef randSigNet_GeneratorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RandSigNetGenUIFigure        matlab.ui.Figure
        SaveButton                   matlab.ui.control.Button
        IndicatorLamp                matlab.ui.control.Lamp
        CommunicationBox             matlab.ui.control.TextArea
        TabGroup                     matlab.ui.container.TabGroup
        NetworkGraphTab              matlab.ui.container.Tab
        ShowLabelsCheckBox           matlab.ui.control.CheckBox
        NetGraphAxes                 matlab.ui.control.UIAxes
        ConnectionMatrixoftheRandomNetworkTab  matlab.ui.container.Tab
        InhibitoryInteractionLabel   matlab.ui.control.Label
        ActivatoryInteractionLabel   matlab.ui.control.Label
        ConnectionMatrix             matlab.ui.control.Table
        RegulatoryExpressionsTab     matlab.ui.container.Tab
        ListRegulatoryExpressions    matlab.ui.control.TextArea
        ControlPanel                 matlab.ui.container.Panel
        GenerateButton               matlab.ui.control.Button
        IndividualNodeSettingsPanel  matlab.ui.container.Panel
        NumOfActInput                matlab.ui.control.NumericEditField
        MaximumNumberofActivatoryInputsLabel  matlab.ui.control.Label
        NumOfInhibInput              matlab.ui.control.NumericEditField
        MaximumNumberofInhibitoryInputsLabel  matlab.ui.control.Label
        OverallNetworkSettingsPanel  matlab.ui.container.Panel
        NumOfFeedbackInt             matlab.ui.control.NumericEditField
        NumberofFeedbackInteractionsLabel  matlab.ui.control.Label
        NumOfOutputNodes             matlab.ui.control.NumericEditField
        NumberofOutputNodesLabel     matlab.ui.control.Label
        NetworkSize                  matlab.ui.control.NumericEditField
        NetworkSizeLabel             matlab.ui.control.Label
        NumOfInputNodes              matlab.ui.control.NumericEditField
        NumberofInputNodesLabel      matlab.ui.control.Label
        RandomSignalingNetworkGeneratorLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        net
        netSize
        numInput
        numOutput
        numFeedback
        numAct
        numInhib
        saveFlag
        netGraph
        nodeNames
        G
        actEdges
        inhEdges
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Get the user inputs
            app.netSize = app.NetworkSize.Value;
            app.numInput = app.NumOfInputNodes.Value;
            app.numOutput = app.NumOfOutputNodes.Value;
            app.numFeedback = app.NumOfFeedbackInt.Value;
            
            app.numAct = app.NumOfActInput.Value;
            app.numInhib = app.NumOfInhibInput.Value;
            
            % Check the input values:
            message = [];
            colorFlag = 0;
            if((~checkValues(app.netSize)) || (app.netSize < 1))
                message = {''; ''; 'Network size must be a positive integer!'};
                colorFlag = 1;
            elseif((~checkValues(app.numInput)) || (app.numInput < 1))
                message = {''; ''; 'Number of inputs must be a positive integer!'; 'The network should have at least one input!'};
                colorFlag = 1;
            elseif((~checkValues(app.numOutput)) || (app.numOutput < 1))
                message = {''; ''; 'Number of outputs must be a positive integer!'; 'The network should have at least one output!'};
                colorFlag = 1;
            elseif((~checkValues(app.numFeedback)) || (app.numFeedback < 0))
                message = {''; ''; 'Number of feedbacks can be either zero or a positive integer!'};
                colorFlag = 1;
            elseif((~checkValues(app.numAct)) || (app.numAct < 1))
                message = {''; ''; 'The maximum number of activators must be a positive integer!'; 'Each node should have at least one activator!'};
                colorFlag = 1;
            elseif((~checkValues(app.numInhib)) || (app.numInhib < 0))
                message = {''; ''; 'The maximum number of inhibitor can be either zero or a positive integer!'};
                colorFlag = 1;
            elseif(app.netSize <= app.numInput)
                message = {''; ''; 'The network size cannot be less than or equal to the number of inputs!'};
                colorFlag = 1;
            elseif(app.netSize <= app.numOutput)
                message = {''; ''; 'The network size cannot be less than or equal to the number of outputs!'};
                colorFlag = 1;
            elseif(app.netSize <= app.numAct)
                message = {''; ''; 'The network size cannot be less than or equal to the maximum number of activators a node can have!'};
                colorFlag = 1;
            elseif(app.netSize <= app.numInhib)
                message = {''; ''; 'The network size cannot be less than or equal to the maximum number of inhibitors a node can have!'};
                colorFlag = 1;
            elseif(app.netSize < app.numInput + app.numOutput)
                message = {''; ''; 'The total number of inputs and the outputs cannot be greater than or equal to the network size!'};
                colorFlag = 1;
            end
            
            
            if(isempty(message))
                
            % Generate the random signaling network
                app.net = RandSigNet_Source(app.netSize, app.numInput, app.numOutput, app.numFeedback, app.numAct, app.numInhib);
            
            % Check if inputs are valid and perform the further actions
                if((app.numFeedback > 0) && (min(sort([app.numInput + 1, app.netSize - app.numOutput - 1])) <= app.numInput || max(sort([app.numInput + 1, app.netSize - app.numOutput - 1])) >= app.netSize - app.numOutput))
                    message = {'';''; 'Warning!! The network size was not properly selected!'; 'There is either no intermedite node or just one intermediate node. So feedback has not been added!'};
                    colorFlag = 2;
                else
                    message = {' ';' '; 'Random signaling network has been generated successfully!'};
                end
                app.ConnectionMatrix.Data = app.net;
                app.ActivatoryInteractionLabel.Visible = 'on';
                app.InhibitoryInteractionLabel.Visible = 'on';
                app.SaveButton.Visible = 'on';
                app.ShowLabelsCheckBox.Visible = 'on';
                app.saveFlag = 1;
                
                % Plot the generated graph
                
                    % Node names:
                    app.nodeNames = {};
                    for i = 1:app.netSize
                        
                        if(i <= app.numInput)
                            app.nodeNames{i} = ['INP' num2str(i)];
                        elseif((i > app.numInput) && (i <= app.netSize - app.numOutput))
                            app.nodeNames{i} = ['INT' num2str(i - app.numInput)];
                        elseif((i > app.netSize - app.numOutput))
                            app.nodeNames{i} = ['OUT' num2str(i - app.netSize + app.numOutput)];
                        end
                    end
                    
                    % Edge colors:
                    [actRowIdx, actColIdx] = find(app.net == 1);
                    [inhRowIdx, inhColIdx] = find(app.net == -1);
                    app.actEdges = [];
                    app.inhEdges = [];
                    for i = 1:size(actRowIdx,1)
                        app.actEdges = [app.actEdges, actColIdx(i,1), actRowIdx(i,1)];
                    end
                    for i = 1:size(inhRowIdx,1)
                        app.inhEdges = [app.inhEdges, inhColIdx(i,1), inhRowIdx(i,1)];
                    end
                
                app.G = digraph(app.net');
                app.netGraph = plot(app.NetGraphAxes,app.G,'Layout','layered', 'Sources', 1:app.numInput, 'Sink', app.netSize:-1:app.netSize - app.numOutput + 1);
                app.netGraph.NodeLabel = {};
                app.netGraph.MarkerSize = 6;    
                highlight(app.netGraph,app.actEdges,'EdgeColor',[0.4660 0.6740 0.1880],'LineWidth',1);
                highlight(app.netGraph,app.inhEdges,'EdgeColor','r','LineWidth',1);
                
                
                % Display connection matrix
                colRowLabels = {};
                idx = 1;
                for i = 1:app.numInput
                    colRowLabels{idx} = ['Input ' int2str(i)];
                    idx = idx + 1;
                end
                
                for i = app.numInput + 1:app.netSize - app.numOutput
                    colRowLabels{idx} = ['Intermediate Node ' int2str(i - app.numInput)];
                    idx = idx + 1;
                end
                
                for i = app.netSize - app.numOutput + 1:app.netSize
                    colRowLabels{idx} = ['Output ' int2str(i - app.netSize + app.numOutput)];
                    idx = idx + 1;
                end
                app.ConnectionMatrix.ColumnName = colRowLabels;
                app.ConnectionMatrix.RowName = colRowLabels;
        
                % Display regulatory interactions
                listRegInt = {};
                listRegInt{1} = "The list of regulatory interactions of the random network:";
                listRegInt{2} = "";
                idx = 3;
                for i = app.numInput + 1:app.netSize
                    for j = 1:app.netSize - app.numOutput
                
                        if(app.net(i,j) == 1)
                            if(j <= app.numInput && i <= app.netSize - app.numOutput)
                                listRegInt{idx} = ['Input ' int2str(j) ' activates Intermediate Node ' int2str(i - app.numInput)];
                                idx = idx + 1;
                            elseif(j <= app.numInput && i > app.netSize - app.numOutput)
                                listRegInt{idx} = ['Input ' int2str(j) ' activates Output ' int2str(i - app.netSize + app.numOutput)];
                                idx = idx + 1;
                            elseif(j > app.numInput && i <= app.netSize - app.numOutput)
                                listRegInt{idx} = ['Intermediate Node ' int2str(j - app.numInput) ' activates Intermediate Node ' int2str(i - app.numInput)];
                                idx = idx + 1;
                            elseif(j > app.numInput && i > app.netSize - app.numOutput)
                                listRegInt{idx} = ['Intermediate Node ' int2str(j - app.numInput) ' activates Output ' int2str(i - app.netSize + app.numOutput)];
                                idx = idx + 1;
                            end
                        elseif(app.net(i,j) == -1)
                            if(j <= app.numInput && i <= app.netSize - app.numOutput)
                                listRegInt{idx} = ['Input ' int2str(j) ' inhibits Intermediate Node ' int2str(i - app.numInput)];
                                idx = idx + 1;
                            elseif(j <= app.numInput && i > app.netSize - app.numOutput)
                                listRegInt{idx} = ['Input ' int2str(j) ' inhibits Output ' int2str(i - app.netSize + app.numOutput)];
                                idx = idx + 1;
                            elseif(j > app.numInput && i <= app.netSize - app.numOutput)
                                listRegInt{idx} = ['Intermediate Node ' int2str(j - app.numInput) ' inhibits Intermediate Node ' int2str(i - app.numInput)];
                                idx = idx + 1;
                            elseif(j > app.numInput && i > app.netSize - app.numOutput)
                                listRegInt{idx} = ['Intermediate Node ' int2str(j - app.numInput) ' inhibits Output ' int2str(i - app.netSize + app.numOutput)];
                                idx = idx + 1;
                            end
                        end
                    end
                    listRegInt{idx} = "";
                    idx = idx + 1;
                end
                app.ListRegulatoryExpressions.Value = cellstr(listRegInt);
                
            end
            
            % Display the message
            app.CommunicationBox.HorizontalAlignment = 'center';
            app.CommunicationBox.Value = message;
            if(colorFlag == 0)
                app.CommunicationBox.FontColor = [0.4660 0.6740 0.1880];
                app.IndicatorLamp.Visible = 'on';
                app.IndicatorLamp.Color = [0.4660 0.6740 0.1880];
            elseif(colorFlag == 1)
                app.CommunicationBox.FontColor = [1.00,0.00,0.00];
                app.IndicatorLamp.Visible = 'on';
                app.IndicatorLamp.Color = [1.00,0.00,0.00];
            elseif(colorFlag == 2)
                app.CommunicationBox.FontColor = [0.85,0.33,0.10];
                app.IndicatorLamp.Visible = 'on';
                app.IndicatorLamp.Color = [0.85,0.33,0.10];
            end
            app.CommunicationBox.FontWeight = 'bold';
            
            function out = checkValues(val)
    
                out = isnumeric(val) & isscalar(val) & isinteger(int64(val));
    
            end
            
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileNameCount = 1;
            
            if(app.saveFlag == 1) % Check if a new network is generated
                
                % Check if the file name being saved is already exist in the folder
                filName_ConnectionMat = ['RandSigNet_ConnectionMatrix_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount) '.csv'];
                filName_RegExp = ['RandSigNet_RegulatoryExpressions_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount) '.txt'];
                filName_NetFigure = ['RandSigNet_NetworkFigure_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount)];
                while(isfile(filName_ConnectionMat))
                    fileNameCount = fileNameCount + 1;
                    filName_ConnectionMat = ['RandSigNet_ConnectionMatrix_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount) '.csv'];
                    filName_RegExp = ['RandSigNet_RegulatoryExpressions_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount) '.txt'];
                    filName_NetFigure = ['RandSigNet_NetworkFigure_NetSize' int2str(app.NetworkSize.Value) '_' int2str(fileNameCount)];
                end
                
                csvwrite(filName_ConnectionMat,app.net)
    
                fID = fopen(filName_RegExp,'w');
        
                fprintf(fID, 'The list of regulatory interactions in the random signaling network:\n\n');
                
                for i = app.numInput + 1:app.netSize
                    for j = 1:app.netSize - app.numOutput
        
                        if(app.net(i,j) == 1)
                            if(j <= app.numInput && i <= app.netSize - app.numOutput)
                                fprintf(fID,['Input ' int2str(j) ' activates Intermediate Node ' int2str(i - app.numInput)]);
                            elseif(j <= app.numInput && i > app.netSize - app.numOutput)
                                fprintf(fID,['Input ' int2str(j) ' activates Output ' int2str(i - app.netSize + app.numOutput)]);
                            elseif(j > app.numInput && i <= app.netSize - app.numOutput)
                                fprintf(fID,['Intermediate Node ' int2str(j - app.numInput) ' activates Intermediate Node ' int2str(i - app.numInput)]);
                            elseif(j > app.numInput && i > app.netSize - app.numOutput)
                                fprintf(fID,['Intermediate Node ' int2str(j - app.numInput) ' activates Output ' int2str(i - app.netSize + app.numOutput)]);
                            end
                            fprintf(fID,'\n');
                        elseif(app.net(i,j) == -1)
                            if(j <= app.numInput && i <= app.netSize - app.numOutput)
                                fprintf(fID,['Input ' int2str(j) ' inhibits Intermediate Node ' int2str(i - app.numInput)]);
                            elseif(j <= app.numInput && i > app.netSize - app.numOutput)
                                fprintf(fID,['Input ' int2str(j) ' inhibits Output ' int2str(i - app.netSize + app.numOutput)]);
                            elseif(j > app.numInput && i <= app.netSize - app.numOutput)
                                fprintf(fID,['Intermediate Node ' int2str(j - app.numInput) ' inhibits Intermediate Node ' int2str(i - app.numInput)]);
                            elseif(j > app.numInput && i > app.netSize - app.numOutput)
                                fprintf(fID,['Intermediate Node ' int2str(j - app.numInput) ' inhibits Output ' int2str(i - app.netSize + app.numOutput)]);
                            end
                            fprintf(fID,'\n');
                        end
                    end
                    fprintf(fID,'\n');
                end
                
                fclose(fID);
                                
                app.CommunicationBox.Value = {'';'';'Saved!'};
                app.CommunicationBox.FontColor = [0.4660 0.6740 0.1880];
                app.IndicatorLamp.Color = [0.4660 0.6740 0.1880];
                app.saveFlag = 0;
            else
                app.CommunicationBox.Value = {'';'';'This network has already been saved!'};
                app.CommunicationBox.FontColor = [0.85,0.33,0.10];
                app.IndicatorLamp.Color = [0.85,0.33,0.10];
            end
            
        end

        % Value changed function: ShowLabelsCheckBox
        function ShowLabelsCheckBoxValueChanged(app, event)
            value = app.ShowLabelsCheckBox.Value;
            
            if(value == 1)
                app.G = digraph(app.net',app.nodeNames);
                app.netGraph = plot(app.NetGraphAxes,app.G,'Layout','layered', 'Sources', 1:app.numInput, 'Sink', app.netSize:-1:app.netSize - app.numOutput + 1);
                app.netGraph.MarkerSize = 6;    
                highlight(app.netGraph,app.actEdges,'EdgeColor',[0.4660 0.6740 0.1880],'LineWidth',1);
                highlight(app.netGraph,app.inhEdges,'EdgeColor','r','LineWidth',1);
            else
                app.G = digraph(app.net');
                app.netGraph = plot(app.NetGraphAxes,app.G,'Layout','layered', 'Sources', 1:app.numInput, 'Sink', app.netSize:-1:app.netSize - app.numOutput + 1);
                app.netGraph.NodeLabel = {};
                app.netGraph.MarkerSize = 6;    
                highlight(app.netGraph,app.actEdges,'EdgeColor',[0.4660 0.6740 0.1880],'LineWidth',1);
                highlight(app.netGraph,app.inhEdges,'EdgeColor','r','LineWidth',1);
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RandSigNetGenUIFigure = uifigure('Visible', 'off');
            app.RandSigNetGenUIFigure.Name = 'RandSigNetGen';
            app.RandomSignalingNetworkGeneratorLabel = uilabel(app.RandSigNetGenUIFigure);
            app.RandomSignalingNetworkGeneratorLabel.Text = 'Random Signaling Network Generator';
            app.ControlPanel = uipanel(app.RandSigNetGenUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.OverallNetworkSettingsPanel = uipanel(app.ControlPanel);
            app.OverallNetworkSettingsPanel.Title = 'Overall Network Settings';
            app.NumberofInputNodesLabel = uilabel(app.OverallNetworkSettingsPanel);
            app.NumberofInputNodesLabel.Text = 'Number of Input Nodes:';
            app.NumOfInputNodes = uieditfield(app.OverallNetworkSettingsPanel, 'numeric');
            app.NetworkSizeLabel = uilabel(app.OverallNetworkSettingsPanel);
            app.NetworkSizeLabel.Text = 'Network Size:';
            app.NetworkSize = uieditfield(app.OverallNetworkSettingsPanel, 'numeric');
            app.NumberofOutputNodesLabel = uilabel(app.OverallNetworkSettingsPanel);
            app.NumberofOutputNodesLabel.Text = 'Number of Output Nodes:';
            app.NumOfOutputNodes = uieditfield(app.OverallNetworkSettingsPanel, 'numeric');
            app.NumberofFeedbackInteractionsLabel = uilabel(app.OverallNetworkSettingsPanel);
            app.NumberofFeedbackInteractionsLabel.Text = 'Number of Feedback Interactions:';
            app.NumOfFeedbackInt = uieditfield(app.OverallNetworkSettingsPanel, 'numeric');
            app.IndividualNodeSettingsPanel = uipanel(app.ControlPanel);
            app.IndividualNodeSettingsPanel.Title = 'Individual Node Settings';
            app.MaximumNumberofInhibitoryInputsLabel = uilabel(app.IndividualNodeSettingsPanel);
            app.MaximumNumberofInhibitoryInputsLabel.Text = 'Maximum Number of Inhibitory Inputs:';
            app.NumOfInhibInput = uieditfield(app.IndividualNodeSettingsPanel, 'numeric');
            app.MaximumNumberofActivatoryInputsLabel = uilabel(app.IndividualNodeSettingsPanel);
            app.MaximumNumberofActivatoryInputsLabel.Text = 'Maximum Number of Activatory Inputs:';
            app.NumOfActInput = uieditfield(app.IndividualNodeSettingsPanel, 'numeric');
            app.GenerateButton = uibutton(app.ControlPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TabGroup = uitabgroup(app.RandSigNetGenUIFigure);
            app.NetworkGraphTab = uitab(app.TabGroup);
            app.NetworkGraphTab.Title = 'Network Graph';
            app.NetGraphAxes = uiaxes(app.NetworkGraphTab);
            app.NetGraphAxes.XTick = [];
            app.NetGraphAxes.YTick = [];
            app.ShowLabelsCheckBox = uicheckbox(app.NetworkGraphTab);
            app.ShowLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLabelsCheckBoxValueChanged, true);
            app.ShowLabelsCheckBox.Visible = 'off';
            app.ShowLabelsCheckBox.Text = 'Show Labels';
            app.ConnectionMatrixoftheRandomNetworkTab = uitab(app.TabGroup);
            app.ConnectionMatrixoftheRandomNetworkTab.Title = 'Connection Matrix of the Random Network';
            app.ConnectionMatrix = uitable(app.ConnectionMatrixoftheRandomNetworkTab);
            app.ConnectionMatrix.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ConnectionMatrix.RowName = {};
            app.ActivatoryInteractionLabel = uilabel(app.ConnectionMatrixoftheRandomNetworkTab);
            app.ActivatoryInteractionLabel.Visible = 'off';
            app.ActivatoryInteractionLabel.Text = '1: Activatory Interaction';
            app.InhibitoryInteractionLabel = uilabel(app.ConnectionMatrixoftheRandomNetworkTab);
            app.InhibitoryInteractionLabel.Visible = 'off';
            app.InhibitoryInteractionLabel.Text = '-1: Inhibitory Interaction';
            app.RegulatoryExpressionsTab = uitab(app.TabGroup);
            app.RegulatoryExpressionsTab.Title = 'Regulatory Expressions';
            app.ListRegulatoryExpressions = uitextarea(app.RegulatoryExpressionsTab);
            app.CommunicationBox = uitextarea(app.RandSigNetGenUIFigure);
            app.CommunicationBox.Value = {''; ''; 'Welcome to Random Signaling Network Generator'};
            app.IndicatorLamp = uilamp(app.RandSigNetGenUIFigure);
            app.IndicatorLamp.Visible = 'off';
            app.SaveButton = uibutton(app.RandSigNetGenUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.RandSigNetGenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = randSigNet_GeneratorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RandSigNetGenUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RandSigNetGenUIFigure)
        end
    end
end